classdef groundStation_rev4_12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ModoRemotoLabel          matlab.ui.control.Label
        ModoRemotoLamp           matlab.ui.control.Lamp
        ControlLabel             matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        Slider                   matlab.ui.control.Slider
        AceleradorremotoLabel    matlab.ui.control.Label
        BujaLabel                matlab.ui.control.Label
        ConectarButton           matlab.ui.control.Button
        ConectadoLampLabel       matlab.ui.control.Label
        ConectadoLamp            matlab.ui.control.Lamp
        Phi1EditFieldLabel       matlab.ui.control.Label
        Phi_1EditField           matlab.ui.control.NumericEditField
        Phi2Label                matlab.ui.control.Label
        Phi_2EditField           matlab.ui.control.NumericEditField
        PuertoEditFieldLabel     matlab.ui.control.Label
        PuertoEditField          matlab.ui.control.EditField
        RightPanel               matlab.ui.container.Panel
        RPMGaugeLabel            matlab.ui.control.Label
        RPMGauge                 matlab.ui.control.Gauge
        DatosentiemporealLabel   matlab.ui.control.Label
        Gauge                    matlab.ui.control.LinearGauge
        AceleradorLabel_2        matlab.ui.control.Label
        ComunicacinListBoxLabel  matlab.ui.control.Label
        ComunicacinListBox       matlab.ui.control.ListBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Variables del programa
        modoOperacion =  "Manual";              % Modo de operación del sistema
        posJOYSTICK = 0;                        % Variable que registra la posición del joystick [-10, 10]
        phi1 = 230;
        phi2 = 260;
       
        registro = [];
        
        alive = 1;
        
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; clc;
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            app.modoOperacion =  value;
            
            
            % Si está en modo remoto
            if app.modoOperacion == "Remoto"
                
                
                % Habilita para cambiar valores y dar input
                app.Slider.Enable = 'on';
                app.Phi_1EditField.Enable = 'on';
                app.Phi_2EditField.Enable = 'on';
                app.ModoRemotoLamp.Color = [0.00,1.00,0.00];
                
                
            else % Modo Manual
                
                % Habilita para cambiar valores y dar input
                app.Slider.Enable = 'off';
                
                app.Phi_1EditField.Enable = 'on';
                app.Phi_2EditField.Enable = 'on';
                app.ModoRemotoLamp.Color = [1.00,1.00,1.00];
                
            end
                  
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
            % Cambia el valor del plot
            if app.modoOperacion == "Remoto"
                app.Gauge.Value = changingValue;
                app.posJOYSTICK = changingValue;
            end
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            
            % ------ Set up de la comunicación serie
            
%             Limpia puertos anteriores por las dudas
            oldPorts = instrfind; delete(oldPorts);
     
            % Identifica a qué puerto conectarse
            port = app.PuertoEditField.Value;
              
            s = serial(port,'BaudRate',9600);
            set(s,'Terminator',10); %10: line feed (\n), 13: carriage return
            
            fopen(s);
            
            % Si abrió con éxito el puerto, prende un testigo y empieza a
            % ejecutarse el programa
            
            app.ConectadoLamp.Color = [0.00,1.00,0.00];
            
%             Set up de txt de valores
%             disp('joystick rpm modoRemoto')
         
            %% Programa
            i = 0;
            
            while app.alive
                
                i = i+1;
                
                if app.modoOperacion == "Remoto"
                    
                    % Lee valores
                    aux=fgetl(s);
                    disp([aux(1:3) ' ' aux(4:6) ' ' 'Y']);
                    
                    % Plot de valores medidos
                    app.RPMGauge.Value = str2num(char(aux(4:6)));
%                     app.RPMGauge.Value = app.RPMGauge.Value + round(app.Gauge.Value)/10;
                    
                    % Feedback a la placa
                    fprintf(s,'%0.3d',app.phi1);
                    fprintf(s,'%0.3d',app.phi2);
                    fprintf(s,'%c',"Y");
                    fprintf(s,'%+0.2d',round(app.posJOYSTICK));
                    
                    commTxt = sprintf('Posición Joystick: %+4d \t---\t RPM: %0.3d \t---\t Modo: Remoto',round(app.Gauge.Value), app.RPMGauge.Value);
                    
                else % Modo Manual
                    
                    % Lee valores
                    aux=fgetl(s);
                    disp([aux(1:3) ' ' aux(4:6) ' ' 'N']);
                    
                    % Plot de valores medidos
                    if aux(1) == "+"
                        pos = str2num(char(aux(2:3)));
                        app.Gauge.Value = pos;
                    else
                        pos = -1* str2num(char(aux(2:3)));
                        app.Gauge.Value = pos;
                    end
                    
                    app.RPMGauge.Value = str2num(char(aux(4:6)));
                    
                    
                    % Feedback a la placa
                    
                        fprintf(s,'%0.3d',app.phi1);
                    fprintf(s,'%0.3d',app.phi2);
                    fprintf(s,'%c',"N");
                    fprintf(s,'%+0.2d',pos);
                    
                    commTxt = sprintf('Posición Joystick: %+4d \t---\t RPM: %0.3d \t---\t Modo: Manual',round(app.Gauge.Value), app.RPMGauge.Value);
                    
                end
                
                % Update de la comm
                app.ComunicacinListBox.Items = [app.ComunicacinListBox.Items, commTxt];
                scroll(app.ComunicacinListBox,'bottom');
                
                % Registro de datos
                app.registro = [app.registro; i round(app.Gauge.Value) app.RPMGauge.Value app.phi1 app.phi2];
                
                pause(0.1);
                
            end
        end

        % Value changed function: Phi_1EditField
        function Phi_1EditFieldValueChanged(app, event)
            value = app.Phi_1EditField.Value;
            
            app.phi1 = value;
            
        end

        % Value changed function: Phi_2EditField
        function Phi_2EditFieldValueChanged(app, event)
            value = app.Phi_2EditField.Value;
            
            app.phi2 = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.alive = 0;
            
            % Plotteos de resumen
            figure();
            Position = [213 65.8000 1.1288e+03 686.4000];
            set(gcf,'position',Position);
            
            ax1 = subplot(2,1,1);
            plot(app.registro(:,1),app.registro(:,3),'-r');grid on; grid minor;
            ylabel('RPM')
            ylim([0,300]);
            
            
            ax2 = subplot(2,1,2);
            plot(app.registro(:,1),app.registro(:,2));grid on; grid minor;
            ylabel('Joystick');
            xlabel('Paquete recibido');
            ylim([-10,10]);
            
            suptitle('Resumen del programa');
            
            linkaxes([ax1,ax2],'x');
            
            output = app.registro;
            
            save dataGroundStation.mat output
            
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {604, 604};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {331, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModoRemotoLabel = uilabel(app.LeftPanel);
            app.ModoRemotoLabel.Text = 'Modo Remoto';
            app.ModoRemotoLamp = uilamp(app.LeftPanel);
            app.ControlLabel = uilabel(app.LeftPanel);
            app.ControlLabel.Text = 'Control';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'Manual', 'Remoto'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Seleccione el modo de operación.'; ''; 'Manual : Se controla con el joystick'; ''; 'Remoto: Se usa el slider de abajo'};
            app.Switch.Value = 'Manual';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [-10 10];
            app.Slider.MajorTicks = [-10 -5 0 5 10];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Tooltip = {'Mover para configurar posición del acelerador'; ''};
            app.AceleradorremotoLabel = uilabel(app.LeftPanel);
            app.AceleradorremotoLabel.Tooltip = {'Mover para configurar posición del acelerador'; ''};
            app.AceleradorremotoLabel.Text = 'Acelerador (remoto)';
            app.BujaLabel = uilabel(app.LeftPanel);
            app.BujaLabel.Text = 'Bujía';
            app.ConectarButton = uibutton(app.LeftPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ConectadoLampLabel = uilabel(app.LeftPanel);
            app.ConectadoLampLabel.Text = 'Conectado ';
            app.ConectadoLamp = uilamp(app.LeftPanel);
            app.Phi1EditFieldLabel = uilabel(app.LeftPanel);
            app.Phi1EditFieldLabel.Text = 'Phi 1';
            app.Phi_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Phi_1EditField.Limits = [0 360];
            app.Phi_1EditField.RoundFractionalValues = 'on';
            app.Phi_1EditField.ValueChangedFcn = createCallbackFcn(app, @Phi_1EditFieldValueChanged, true);
            app.Phi_1EditField.Value = 230;
            app.Phi2Label = uilabel(app.LeftPanel);
            app.Phi2Label.Text = 'Phi 2';
            app.Phi_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Phi_2EditField.Limits = [0 360];
            app.Phi_2EditField.RoundFractionalValues = 'on';
            app.Phi_2EditField.ValueChangedFcn = createCallbackFcn(app, @Phi_2EditFieldValueChanged, true);
            app.Phi_2EditField.Value = 260;
            app.PuertoEditFieldLabel = uilabel(app.LeftPanel);
            app.PuertoEditFieldLabel.Text = 'Puerto';
            app.PuertoEditField = uieditfield(app.LeftPanel, 'text');
            app.PuertoEditField.Tooltip = {'Seleccione el puerto al cual conectarse.'; ''; 'Ej: ''COM7'''};
            app.PuertoEditField.Value = 'COM7';
            app.RightPanel = uipanel(app.GridLayout);
            app.RPMGaugeLabel = uilabel(app.RightPanel);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.RightPanel, 'circular');
            app.RPMGauge.Limits = [0 300];
            app.RPMGauge.Tooltip = {'RPM medidas con el encoder'; ''};
            app.DatosentiemporealLabel = uilabel(app.RightPanel);
            app.DatosentiemporealLabel.Text = 'Datos en tiempo real';
            app.Gauge = uigauge(app.RightPanel, 'linear');
            app.Gauge.Limits = [-10 10];
            app.Gauge.Tooltip = {'Posición del joystick de acelerador'};
            app.AceleradorLabel_2 = uilabel(app.RightPanel);
            app.AceleradorLabel_2.Text = 'Acelerador';
            app.ComunicacinListBoxLabel = uilabel(app.RightPanel);
            app.ComunicacinListBoxLabel.Text = 'Comunicación';
            app.ComunicacinListBox = uilistbox(app.RightPanel);
            app.ComunicacinListBox.Items = {'', ''};
            app.ComunicacinListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groundStation_rev4_12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end