classdef groundStation_sinPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ModoRemotoLabel         matlab.ui.control.Label
        ModoRemotoLamp          matlab.ui.control.Lamp
        ControlLabel            matlab.ui.control.Label
        Switch                  matlab.ui.control.Switch
        Slider                  matlab.ui.control.Slider
        AceleradorremotoLabel   matlab.ui.control.Label
        Phi_1EditFieldLabel     matlab.ui.control.Label
        Phi_1EditField          matlab.ui.control.EditField
        Phi_2EditFieldLabel     matlab.ui.control.Label
        Phi_2EditField          matlab.ui.control.EditField
        BujaLabel               matlab.ui.control.Label
        ConectarButton          matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        RPMGaugeLabel           matlab.ui.control.Label
        RPMGauge                matlab.ui.control.Gauge
        DatosentiemporealLabel  matlab.ui.control.Label
        Gauge                   matlab.ui.control.LinearGauge
        AceleradorLabel_2       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        % Variables del programa
        modoOperacion =  "Manual";              % Modo de operación del sistema
        posJOYSTICK = 0;                        % Variable que registra la posición del joystick [-10, 10]
        phi1 = '230';
        phi2 = '260';
        
        
        % Setteo del programa
        s = [];
        
    end


    methods (Access = private)
        
        function UART_MSP430 = initSerial(app)
            oldPorts = instrfind; delete(oldPorts);
            
            PORT      = "COM7"; % Ver y modificar en administrador de dispositivos
            BAUDRATE  = 9600;
            nDATABITS = 8;
            nSTOPBITS = 1;
            
            UART_MSP430 = serialport(PORT,BAUDRATE,'DataBits',nDATABITS,'StopBits',nSTOPBITS);
            
        end
        
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            app.modoOperacion =  value;
            
            % Si está en modo remoto
            if app.modoOperacion == "Remoto"
                
                % Habilita para cambiar valores y dar input
                app.Slider.Enable = 'on';
                app.Phi_1EditField.Enable = 'on';
                app.Phi_2EditField.Enable = 'on';
                app.ModoRemotoLamp.Color = [0.00,1.00,0.00];
                
                
            else % Modo Manual
                
                % Habilita para cambiar valores y dar input
                app.Slider.Enable = 'off';
                app.Phi_1EditField.Enable = 'off';
                app.Phi_2EditField.Enable = 'off';
                app.ModoRemotoLamp.Color = [1.00,1.00,1.00];
                
                
            end
            
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            if app.modoOperacion == "Remoto"
                % Si cambio el slider (Solo posible en modo remoto, guarda el valor)
                
            end
            

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
            % Cambia el valor del plot
            if app.modoOperacion == "Remoto"
                app.Gauge.Value = changingValue;
                app.posJOYSTICK = changingValue;
            end
            
        end

        % Value changed function: Phi_1EditField
        function Phi_1EditFieldValueChanged(app, event)
            value = app.Phi_1EditField.Value;
            
            app.phi1= value;
            
        end

        % Value changed function: Phi_2EditField
        function Phi_2EditFieldValueChanged(app, event)
            value = app.Phi_2EditField.Value;
            
            app.phi2= value;
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            %                     app.s = initSerial(app);


            disp('joystick rpm phi1 phi2 modoRemoto')
            
            % Limpia puertos anteriores por las dudas
            oldPorts = instrfind; delete(oldPorts);
            s     = serial('COM7','BaudRate',9600);
            set(s,'Terminator',10); %10: line feed (\n), 13: carriage return
            
            fopen(s);
            
            while 1
                
                if app.modoOperacion == "Remoto"
                                  
                    aux=fgetl(s);
                    disp([aux(1:3) ' ' aux(4:6) ' ' 'Y']);
                                     
                    % Plot de valores
                    
                    app.RPMGauge.Value = str2num(char(aux(4:6)));

                    fprintf(s,'%0.3d',str2num(app.phi1));
                    fprintf(s,'%0.3d',str2num(app.phi2));
                    fprintf(s,'%c',"Y");
                    fprintf(s,'%+0.2d',round(app.posJOYSTICK));
                    
                else % Modo Manual
                              
                    
                    aux=fgetl(s);
                    disp([aux(1:3) ' ' aux(4:6) ' ' 'N']);


                    % Plot de valores
                    if aux(1) == "+"
                        pos = str2num(char(aux(2:3)));
                        app.Gauge.Value = str2num(char(aux(2:3)));
                    else
                         pos = -1* str2num(char(aux(2:3)));
                        app.Gauge.Value = -1*str2num(char(aux(2:3)));
                    end
                    
                    app.RPMGauge.Value = str2num(char(aux(4:6)));
                    
                    
                    % Envía a la placa
                    
                    fprintf(s,'%0.3d',str2num(app.phi1));
                    fprintf(s,'%0.3d',str2num(app.phi2));
                    fprintf(s,'%c',"N");
                    fprintf(s,'%+0.2d',pos);
                    
                    %
                    
                    
                end
                
                pause(0.1);
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {377, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModoRemotoLabel = uilabel(app.LeftPanel);
            app.ModoRemotoLabel.Text = 'Modo Remoto';
            app.ModoRemotoLamp = uilamp(app.LeftPanel);
            app.ControlLabel = uilabel(app.LeftPanel);
            app.ControlLabel.Text = 'Control';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Items = {'Manual', 'Remoto'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Manual';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [-10 10];
            app.Slider.MajorTicks = [-10 -5 0 5 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Enable = 'off';
            app.Slider.Tooltip = {'Mover para configurar posición del acelerador'; ''};
            app.AceleradorremotoLabel = uilabel(app.LeftPanel);
            app.AceleradorremotoLabel.Tooltip = {'Mover para configurar posición del acelerador'; ''};
            app.AceleradorremotoLabel.Text = 'Acelerador (remoto)';
            app.Phi_1EditFieldLabel = uilabel(app.LeftPanel);
            app.Phi_1EditFieldLabel.Text = 'Phi_1';
            app.Phi_1EditField = uieditfield(app.LeftPanel, 'text');
            app.Phi_1EditField.ValueChangedFcn = createCallbackFcn(app, @Phi_1EditFieldValueChanged, true);
            app.Phi_1EditField.Enable = 'off';
            app.Phi_1EditField.Value = '230';
            app.Phi_2EditFieldLabel = uilabel(app.LeftPanel);
            app.Phi_2EditFieldLabel.Text = 'Phi_2';
            app.Phi_2EditField = uieditfield(app.LeftPanel, 'text');
            app.Phi_2EditField.ValueChangedFcn = createCallbackFcn(app, @Phi_2EditFieldValueChanged, true);
            app.Phi_2EditField.Enable = 'off';
            app.Phi_2EditField.Value = '260';
            app.BujaLabel = uilabel(app.LeftPanel);
            app.BujaLabel.Text = 'Bujía';
            app.ConectarButton = uibutton(app.LeftPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.RightPanel = uipanel(app.GridLayout);
            app.RPMGaugeLabel = uilabel(app.RightPanel);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.RightPanel, 'circular');
            app.RPMGauge.Limits = [0 200];
            app.DatosentiemporealLabel = uilabel(app.RightPanel);
            app.DatosentiemporealLabel.Text = 'Datos en tiempo real';
            app.Gauge = uigauge(app.RightPanel, 'linear');
            app.Gauge.Limits = [-10 10];
            app.AceleradorLabel_2 = uilabel(app.RightPanel);
            app.AceleradorLabel_2.Text = 'Acelerador';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groundStation_sinPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end